classdef Character_recognition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        extraxtButton             matlab.ui.control.Button
        resultButton              matlab.ui.control.Button
        loadapictureListBoxLabel  matlab.ui.control.Label
        loadapictureListBox       matlab.ui.control.ListBox
        UIAxes_1                  matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes_3                  matlab.ui.control.UIAxes
        UIAxes_4                  matlab.ui.control.UIAxes
        UIAxes_5                  matlab.ui.control.UIAxes
        UIAxes_6                  matlab.ui.control.UIAxes
        UIAxes_7                  matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end


    properties (Access = public)
        im; % Description
        count % Description
    end

    properties (Access = public)
        Code = '0'; % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             set(app.UIAxes,'visible','off');
             set(app.UIAxes_1,'visible','off');
             set(app.UIAxes_2,'visible','off');
             set(app.UIAxes_3,'visible','off');
             set(app.UIAxes_4,'visible','off');
             set(app.UIAxes_5,'visible','off');
             set(app.UIAxes_6,'visible','off');
             set(app.UIAxes_7,'visible','off');
        end

        % Callback function
        function showButtonPushed(app, event)
            
        end

        % Button pushed function: extraxtButton
        function extraxtButtonPushed(app, event)
            for k = 1 : 7
                kk = int2str(k);
                delete([kk,'.jpg']);
            end
            img = app.im;
            img = rgb2gray(img);
            img=imadjust(img,[0 1],[1 0]);
            img = ~im2bw(img);
            img = bwareaopen(img,1500);
            img(:,end) = 0;
            img=clip(img);
            num_filas=size(img,2);
            s1 = 1;
            i = 1;
            app.UIAxes_1.XTick = [0.1,0.2,0.3,0.4,0.6,0.7];
            row = size(img,1);
            while sum(img(:,s1)) == 0 && s1 < num_filas
            s1 = s1 + 1;
            end
            s = s1 + 1;
            while s <= num_filas
                if sum(img(:,s)) == 0
                    nm = img(:,1:s-1); 
                    rm = img(:,s:end);
                    fl = clip(nm);
                    re=clip(rm);
                    %figure;
                    %imshow(fl);
                    ii = int2str(i);
                    fl = imresize(fl,[40,20]);
                    imwrite(fl,[ii,'.jpg']); 


                    if i == 1
                    imshow(fl ,'Parent' ,app.UIAxes_1); 
                    elseif i == 2
                    imshow(fl ,'Parent' ,app.UIAxes_2);
                   
                    elseif i == 3
                    imshow(fl ,'Parent' ,app.UIAxes_3);
                    
                    elseif i == 4
                    imshow(fl ,'Parent' ,app.UIAxes_4);
                    
                    elseif i == 5
                    imshow(fl ,'Parent' ,app.UIAxes_5);
                    
                    elseif i == 6
                    imshow(fl ,'Parent' ,app.UIAxes_6);
                    
                    elseif i == 7
                    imshow(fl ,'Parent' ,app.UIAxes_7);
                    else
                    imshow(fl ,'Parent' ,app.UIAxes_7);  
             
                    end
                    img = re;
                    if re == 0
                        break;
                    else
                        i = i + 1;
                    end
                    if i > 7
                       break
                    end
                    
                    num_filas=size(img,2);
                    s1 = 1;
                    while s1 < num_filas && sum(img(:,s1)) == 0 
                    s1 = s1 + 1;
                    end
                    s = s1;
                else
                    if s == size(img,2)
                        img(:,s) = 0;
                    else
                        s = s + 1;
                        continue
                    end
                    
                    
                end
            end
            app.count = i - 1;
        end

        % Value changed function: loadapictureListBox
        function loadapictureListBoxValueChanged(app, event)
            value = app.loadapictureListBox.Value;
            if value == "none"
                
                imshow(app.im,'Parent' ,app.UIAxes);
            elseif value == "web"
                app.im = imread('awsl.jpeg');
                imshow(app.im ,'Parent' ,app.UIAxes);
            else
                app.im = imread('123.png');
                imshow(app.im ,'Parent' ,app.UIAxes);           
            end
            
        end

        % Button pushed function: resultButton
        function resultButtonPushed(app, event)
         liccode=char(['0':'9' 'A':'Z' '京','辽','鲁','陕','苏','豫','浙','贵']);
         subBw2 = zeros(40,20);
         num = 1;   
         Code = '0';
         Code(1:end) = 0;
         for i = 1:app.count 
            ii = int2str(i);  
            word = imread([ii,'.jpg']); 
            word = im2bw(word);
            segBw2 = imresize(word,[40 20]);
             if i == 1  
                kMin = 37;
                kMax = 44;
             else
             kMin = 1;
             kMax = 36;
             end
        
            l = 1;
            error = 0;
            for k = kMin : kMax
        
                fname = strcat('model/',liccode(k),'.jpg');
                samBw2 = imread(fname);
                samBw2 = im2bw(samBw2); 
                samBw2 = imresize(samBw2,[40,20]);
                Dmax = 0;
                for i1 = 1:40
                    for j1 = 1:20
                        subBw2(i1, j1) = segBw2(i1, j1) - samBw2(i1 ,j1);
                        if subBw2(i1, j1) ~= 0
                            Dmax = Dmax + 1;
                        end
                    end
                end
                error(l) = Dmax;
                l = l + 1;
            end
            errorMin = min(error);
            findc = find(error == errorMin);
            disp(findc);
            Code(num*2 - 1) = liccode(findc + kMin - 1);
            Code(num*2) = ' ';
            num = num + 1;
           
         end
         disp(Code);
         msgbox(Code,'识别出的车牌号');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.extraxtButton = uibutton(app.UIFigure, 'push');
            app.extraxtButton.ButtonPushedFcn = createCallbackFcn(app, @extraxtButtonPushed, true);
            app.extraxtButton.Text = 'extraxt';
            app.resultButton = uibutton(app.UIFigure, 'push');
            app.resultButton.ButtonPushedFcn = createCallbackFcn(app, @resultButtonPushed, true);
            app.resultButton.Text = 'result';
            app.loadapictureListBoxLabel = uilabel(app.UIFigure);
            app.loadapictureListBoxLabel.Text = 'load a picture';
            app.loadapictureListBox = uilistbox(app.UIFigure);
            app.loadapictureListBox.Items = {'none', 'web', 'real'};
            app.loadapictureListBox.ValueChangedFcn = createCallbackFcn(app, @loadapictureListBoxValueChanged, true);
            app.loadapictureListBox.Value = 'none';
            app.UIAxes_1 = uiaxes(app.UIFigure);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_3 = uiaxes(app.UIFigure);
            app.UIAxes_4 = uiaxes(app.UIFigure);
            app.UIAxes_5 = uiaxes(app.UIFigure);
            app.UIAxes_6 = uiaxes(app.UIFigure);
            app.UIAxes_7 = uiaxes(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Character_recognition

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end