classdef Hough < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        loadButton         matlab.ui.control.Button
        my_huffButton      matlab.ui.control.Button
        matlab_funcButton  matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end


    properties (Access = public)
        im1 % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        set(app.UIAxes,'visible','off');   
        end

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            app.im1 = imread('school5.jpg');
            imshow(app.im1,'Parent',app.UIAxes);
        end

        % Button pushed function: my_huffButton
        function my_huffButtonPushed(app, event)
            I = app.im1;
            I = rgb2gray(I);
            I = edge(I,'canny',0.12);
            [rows, cols] = size(I);
             
                theta_maximum = 90;
                rho_maximum = floor(sqrt(rows^2 + cols^2)) - 1;
                theta_range = -theta_maximum:theta_maximum - 1;
                rho_range = -rho_maximum:rho_maximum;
             
                Hough = zeros(length(rho_range), length(theta_range));
                
                for row = 1:rows
                  
                    for col = 1:cols
                        if I(row, col) > 0
                            x = col - 1;
                            y = row - 1;
                            for theta = theta_range
                                rho = round((x * cosd(theta)) + (y * sind(theta)));                   
                                rho_index = rho + rho_maximum + 1;
                                theta_index = theta + theta_maximum + 1;
                                Hough(rho_index, theta_index) = Hough(rho_index, theta_index) + 1;
                            end
                        end
                    end
                end
            Peaks=houghpeaks(Hough,9);
            lines=houghlines(I,theta_range,rho_range,Peaks);
            
            % draw the line
            figure;
            imshow(I,[]),hold on 
             for k=1:length(lines)
            xy=[lines(k).point1;lines(k).point2];   
            plot(xy(:,1),xy(:,2),'LineWidth',4,'color',[0 1 0]),hold on
             end
            k = 2;
            while(k<=length(lines))
                nlind = 0;
                while(lines(k).theta == lines(k-1).theta && lines(k).rho == lines(k-1).rho)
                    nlind = nlind + 1;
                    newlines(nlind) = lines(k-1);
                    newlines(nlind).point2=lines(k).point2;
                    newxy=[newlines(nlind).point1;newlines(nlind).point2];
                    plot(newxy(:,1),newxy(:,2),'LineWidth',4,'color',[0 1 0]);
                    k=k+1;       
                    if(k>length(lines))
                        break;
                    end
                end 
                k = k + 1;
            end
        end

        % Button pushed function: matlab_funcButton
        function matlab_funcButtonPushed(app, event)
            I = app.im1;
            I = rgb2gray(I);
            I = edge(I,'canny',0.12);
            [Hough,Theta,Rho] =hough(I);%my_hough_line(I);%hough(I,'RhoResolution', 0.5);
            
            Peaks=houghpeaks(Hough,9);
            lines=houghlines(I,Theta,Rho,Peaks);
            
            % draw the line
            figure;
            imshow(I,[]),title('Hough Transform Detect Result'),hold on 
             for k=1:length(lines)
            xy=[lines(k).point1;lines(k).point2];   
            plot(xy(:,1),xy(:,2),'LineWidth',4,'color',[0 1 0]),hold on
             end
            k = 2;
            while(k<=length(lines))
                nlind = 0;
                while(lines(k).theta == lines(k-1).theta && lines(k).rho == lines(k-1).rho)
                    nlind = nlind + 1;
                    newlines(nlind) = lines(k-1);
                    newlines(nlind).point2=lines(k).point2;
                    newxy=[newlines(nlind).point1;newlines(nlind).point2];
                    plot(newxy(:,1),newxy(:,2),'LineWidth',4,'color',[0 1 0]);
                    k=k+1;       
                    if(k>length(lines))
                        break;
                    end
                end 
                k = k + 1;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.my_huffButton = uibutton(app.UIFigure, 'push');
            app.my_huffButton.ButtonPushedFcn = createCallbackFcn(app, @my_huffButtonPushed, true);
            app.my_huffButton.Text = 'my_huff';
            app.matlab_funcButton = uibutton(app.UIFigure, 'push');
            app.matlab_funcButton.ButtonPushedFcn = createCallbackFcn(app, @matlab_funcButtonPushed, true);
            app.matlab_funcButton.Text = 'matlab_func';
            app.UIAxes = uiaxes(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Hough

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end