classdef compare_gradien_laplac < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        loadButton     matlab.ui.control.Button
        sobelButton    matlab.ui.control.Button
        prewittButton  matlab.ui.control.Button
        rebertsButton  matlab.ui.control.Button
        cannyButton    matlab.ui.control.Button
        UIAxes         matlab.ui.control.UIAxes
        UIAxes_2       matlab.ui.control.UIAxes
        UIAxes_3       matlab.ui.control.UIAxes
        UIAxes_4       matlab.ui.control.UIAxes
    end


    properties (Access = public)
        im1 % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         set(app.UIAxes,'visible','off');   
         set(app.UIAxes_2,'visible','off'); 
         set(app.UIAxes_3,'visible','off'); 
         set(app.UIAxes_4,'visible','off'); 
        end

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            app.im1 = imread('comp.jpg');
            app.im1 = rgb2gray(app.im1);
            imshow(app.im1,'Parent',app.UIAxes);
        end

        % Button pushed function: sobelButton
        function sobelButtonPushed(app, event)
            im = edge(app.im1,'sobel',0.08);
            imshow(im,'Parent',app.UIAxes);
        end

        % Button pushed function: prewittButton
        function prewittButtonPushed(app, event)
            im = edge(app.im1,'prewitt',0.08);
            imshow(im,'Parent',app.UIAxes_2);
        end

        % Button pushed function: rebertsButton
        function rebertsButtonPushed(app, event)
            im = edge(app.im1,'roberts',0.08);
            imshow(im,'Parent',app.UIAxes_3);
        end

        % Button pushed function: cannyButton
        function cannyButtonPushed(app, event)
            im = edge(app.im1,'canny',0.08);
            imshow(im,'Parent',app.UIAxes_4);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.sobelButton = uibutton(app.UIFigure, 'push');
            app.sobelButton.ButtonPushedFcn = createCallbackFcn(app, @sobelButtonPushed, true);
            app.sobelButton.Text = 'sobel';
            app.prewittButton = uibutton(app.UIFigure, 'push');
            app.prewittButton.ButtonPushedFcn = createCallbackFcn(app, @prewittButtonPushed, true);
            app.prewittButton.Text = 'prewitt';
            app.rebertsButton = uibutton(app.UIFigure, 'push');
            app.rebertsButton.ButtonPushedFcn = createCallbackFcn(app, @rebertsButtonPushed, true);
            app.rebertsButton.Text = 'reberts';
            app.cannyButton = uibutton(app.UIFigure, 'push');
            app.cannyButton.ButtonPushedFcn = createCallbackFcn(app, @cannyButtonPushed, true);
            app.cannyButton.Text = 'canny';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_3 = uiaxes(app.UIFigure);
            app.UIAxes_4 = uiaxes(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = compare_gradien_laplac

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end