classdef my_and_mat_canny < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes2_2                    matlab.ui.control.UIAxes
        loadButton                   matlab.ui.control.Button
        UIAxes2_3                    matlab.ui.control.UIAxes
        to_grayButton                matlab.ui.control.Button
        sobel_detectButton           matlab.ui.control.Button
        nonmaximumsuppressionButton  matlab.ui.control.Button
        hysteresisButton             matlab.ui.control.Button
        mat_functionButton           matlab.ui.control.Button
        revmove_noiseButton          matlab.ui.control.Button
    end


    properties (Access = public)
        im1 % Description
        im2
        im3
        px
        py
        direction
        high_thresh = 120;
        low_thresh = 40;
        H
        W
        f2
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.UIAxes2_2,'visible','off');
            set(app.UIAxes2_3,'visible','off');
        end

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            app.im1 = imread('school5.jpg');
            imshow(app.im1 ,'Parent' ,app.UIAxes2_2);
            
        end

        % Button pushed function: to_grayButton
        function to_grayButtonPushed(app, event)
            app.im1 = rgb2gray(app.im1);
            imshow(app.im1 ,'Parent' ,app.UIAxes2_2);
            app.im3 = app.im1;
        end

        % Button pushed function: sobel_detectButton
        function sobel_detectButtonPushed(app, event)
            f = double(app.im2);
            [app.H,app.W] = size(app.im2);
            
            Gx = [-1,-2,-1;0,0,0;1,2,1];
            Gy = Gx';
            for i = 2:app.H - 1
                for j = 2:app.W - 1
                   temp = f(i-1:i+1,j-1:j+1);
                   app.px(i,j) = sum(sum(Gx.*temp));
                   app.py(i,j) = sum(sum(Gy.*temp));
                   G = sqrt(app.px(i,j).^2+app.py(i,j).^2);
                   
                       app.im2(i,j) = G; 
                   %the direction of the edge: Theta = invtan(Gy/Gx)
                   Theta = atand(app.py(i,j)/app.px(i,j));
                   %convert the angle in 4 ranges
                    if (Theta >= 0 && Theta < 45)
                        app.direction(i,j) = 2;
                    elseif (Theta >= 45 && Theta < 90)
                        app.direction(i,j) = 3;
                    elseif (Theta >= 90 && Theta < 135)
                        app.direction(i,j) = 0;
                    else
                        app.direction(i,j) = 1;
                    end
                end
            end
            imshow(app.im2 ,'Parent' ,app.UIAxes2_2);
            app.f2 = double(app.im2);
        end

        % Button pushed function: revmove_noiseButton
        function revmove_noiseButtonPushed(app, event)
            h_gaussian = fspecial('gaussian',[3,3],3);
            app.im2 = imfilter(app.im1,h_gaussian,'replicate');
            imshow(app.im2,'Parent' ,app.UIAxes2_2);
        end

        % Button pushed function: nonmaximumsuppressionButton
        function nonmaximumsuppressionButtonPushed(app, event)
            for i = 2:app.H - 1
            for j = 2:app.W - 1
                 right_d = app.f2(i,j+1);
                 down_d = app.f2(i+1,j);
                 left_d = app.f2(i,j-1);
                 top_d = app.f2(i-1,j);
                 top_right = app.f2(i-1,j+1);
                 top_left = app.f2(i-1,j-1);
                 down_right = app.f2(i+1,j+1);
                 down_left = app.f2(i-1,j+1);
                 px2 = app.f2(i,j);
                 if app.direction(i,j) == 0
                  d = abs(app.px(i,j)/app.py(i,j));
                  G1 = right_d*(1-d) + top_right*d;
                  G2 = left_d*(1-d) + down_left*d;
                 elseif app.direction(i,j) == 1
                   d = abs(app.px(i,j)/app.py(i,j));
                   G1 = top_d*(1-d) + top_right*d;
                   G2 = down_d*(1-d) + down_left*d;
                 elseif app.direction(i,j) == 2
                    d = abs(app.px(i,j)/app.py(i,j));
                    G1 = top_d*(1-d) + top_left*d;
                    G2 = down_d*(1-d) + down_right*d;
                  elseif app.direction(i,j) == 3
                     d = abs(app.px(i,j)/app.py(i,j));
                     G1 = left_d*(1-d) + top_left*d;
                     G2 = right_d*(1-d) + down_right*d;
                 else
                     G1 = app.high_thresh;
                     G2 = app.high_thresh;
                 end
                  if px2 <= G1 || px2 <= G2
                      app.im2(i,j) = 0;
                  end 
            end
            end
            imshow(app.im2,'Parent' ,app.UIAxes2_2);
        end

        % Button pushed function: hysteresisButton
        function hysteresisButtonPushed(app, event)
            for i = 2:app.H - 1
            for j = 2:app.W - 1
                if app.im2(i,j) > app.high_thresh
                    app.im2(1,2) = 255;
                elseif app.im2(i,j) > app.low_thresh
                    app.im2(i,j) = app.low_thresh;
                else
                    app.im2(i,j) = 0;
                end
            end
            end
            imshow(app.im2,'Parent' ,app.UIAxes2_2);
        end

        % Button pushed function: mat_functionButton
        function mat_functionButtonPushed(app, event)
            app.im3 =  edge(app.im3,'canny',0.2);
            imshow(app.im3,'Parent' ,app.UIAxes2_3);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.UIAxes2_3 = uiaxes(app.UIFigure);
            app.to_grayButton = uibutton(app.UIFigure, 'push');
            app.to_grayButton.ButtonPushedFcn = createCallbackFcn(app, @to_grayButtonPushed, true);
            app.to_grayButton.Text = 'to_gray';
            app.sobel_detectButton = uibutton(app.UIFigure, 'push');
            app.sobel_detectButton.ButtonPushedFcn = createCallbackFcn(app, @sobel_detectButtonPushed, true);
            app.sobel_detectButton.Text = 'sobel_detect';
            app.nonmaximumsuppressionButton = uibutton(app.UIFigure, 'push');
            app.nonmaximumsuppressionButton.ButtonPushedFcn = createCallbackFcn(app, @nonmaximumsuppressionButtonPushed, true);
            app.nonmaximumsuppressionButton.Text = 'non-maximum suppression';
            app.hysteresisButton = uibutton(app.UIFigure, 'push');
            app.hysteresisButton.ButtonPushedFcn = createCallbackFcn(app, @hysteresisButtonPushed, true);
            app.hysteresisButton.Text = 'hysteresis ';
            app.mat_functionButton = uibutton(app.UIFigure, 'push');
            app.mat_functionButton.ButtonPushedFcn = createCallbackFcn(app, @mat_functionButtonPushed, true);
            app.mat_functionButton.Text = 'mat_function';
            app.revmove_noiseButton = uibutton(app.UIFigure, 'push');
            app.revmove_noiseButton.ButtonPushedFcn = createCallbackFcn(app, @revmove_noiseButtonPushed, true);
            app.revmove_noiseButton.Text = 'revmove_noise';
        end
    end

    methods (Access = public)

        % Construct app
        function app = my_and_mat_canny

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end