classdef my_and_mat_onegradient < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        loadButton        matlab.ui.control.Button
        my_sobelButton    matlab.ui.control.Button
        sobelButton       matlab.ui.control.Button
        my_robertButton   matlab.ui.control.Button
        robertButton      matlab.ui.control.Button
        my_perwittButton  matlab.ui.control.Button
        perwittButton     matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
        toGrayButton      matlab.ui.control.Button
        UIAxes2           matlab.ui.control.UIAxes
    end


    properties (Access = public)
        im1; % origin
        im2; %result
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.UIAxes,'visible','off');
            set(app.UIAxes2,'visible','off');
        end

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            app.im1 = imread('school1.jpg');
            imshow(app.im1,'Parent',app.UIAxes);
        end

        % Button pushed function: my_sobelButton
        function my_sobelButtonPushed(app, event)
            app.im1 = mat2gray(app.im1);
            f = double(app.im1);
            [H,W] = size(app.im1);
            app.im2 = app.im1;
            h_gaussian = fspecial('gaussian',[3,3],3);
            app.im2 = imfilter(app.im2,h_gaussian,'replicate');
            Gx = [-1,-2,-1;0,0,0;1,2,1];
            Gy = Gx';
            sobel_threshold = 0.8;% set a threshold similar to what in the edge function
            
            for i = 1:H - 2
                for j = 1:W - 2
                   temp = f(i:i+2,j:j+2);
                   px = sum(sum(Gx.*temp));
                   py = sum(sum(Gy.*temp));
                   G = sqrt(px.^2+py.^2);
                   if G > sobel_threshold
                       app.im2(i,j) = 255;
                   else
                       app.im2(i,j) = 0;
                   end   
                end
            end
            app.im2 = im2uint8(app.im2);
            imshow(app.im2,'Parent',app.UIAxes);
        end

        % Button pushed function: toGrayButton
        function toGrayButtonPushed(app, event)
            if size(app.im1,3) == 3
            app.im1 = rgb2gray(app.im1);
            end
            imshow(app.im1,'Parent',app.UIAxes2);
        end

        % Button pushed function: sobelButton
        function sobelButtonPushed(app, event)
            app.im2 = im2uint8(edge(app.im1,'sobel',0.08,'both'));
            imshow(app.im2,'Parent',app.UIAxes2);
        end

        % Button pushed function: my_robertButton
        function my_robertButtonPushed(app, event)
            app.im1 = mat2gray(app.im1);
            f = double(app.im1);
            [H,W] = size(app.im1);
            app.im2 = app.im1;
            h_gaussian = fspecial('gaussian',[3,3],3);
            app.im2 = imfilter(app.im2,h_gaussian,'replicate');
            Gx = [1,0;0,-1];
            Gy = [0,1;-1,0];
            sobel_threshold = 0.25;
            
            for i = 1:H - 1
                for j = 1:W - 1
                   temp = f(i:i+1,j:j+1);
                   px = sum(sum(Gx.*temp));
                   py = sum(sum(Gy.*temp));
                   G = sqrt(px.^2+py.^2);
                   if G > sobel_threshold
                       app.im2(i,j) = 255;
                   else
                       app.im2(i,j) = 0;
                   end   
                end
            end
            
            app.im2 = im2uint8(app.im2);
            imshow(app.im2,'Parent',app.UIAxes);
        end

        % Button pushed function: robertButton
        function robertButtonPushed(app, event)
            app.im2 = im2uint8(edge(app.im1,'roberts',0.08));
            imshow(app.im2,'Parent',app.UIAxes2);
        end

        % Button pushed function: my_perwittButton
        function my_perwittButtonPushed(app, event)
            app.im1 = mat2gray(app.im1);
            f = double(app.im1);
            [H,W] = size(app.im1);
            app.im2 = app.im1;
            h_gaussian = fspecial('gaussian',[3,3],3);
            app.im2 = imfilter(app.im2,h_gaussian,'replicate');
            Gx = [-1,0,1;-1,0,1;-1,0,1];
            Gy = Gx';
            sobel_threshold = 0.6;
            
            for i = 1:H - 2
                for j = 1:W - 2
                   temp = f(i:i+2,j:j+2);
                   px = sum(sum(Gx.*temp));
                   py = sum(sum(Gy.*temp));
                   G = sqrt(px.^2+py.^2);
                   if G > sobel_threshold
                       app.im2(i,j) = G;
                   else
                       app.im2(i,j) = 0;
                   end   
                end
            end
            app.im2 = im2uint8(app.im2);
            imshow(app.im2,'Parent',app.UIAxes);
        end

        % Button pushed function: perwittButton
        function perwittButtonPushed(app, event)
            app.im2 = edge(app.im1,'prewitt',0.08);
            imshow(app.im2,'Parent',app.UIAxes2);
        end

        % Callback function
        function cannyButtonPushed(app, event)
            app.im2 = edge(app.im1,'canny',0.12);
            imshow(app.im2,'Parent',app.UIAxes);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.my_sobelButton = uibutton(app.UIFigure, 'push');
            app.my_sobelButton.ButtonPushedFcn = createCallbackFcn(app, @my_sobelButtonPushed, true);
            app.my_sobelButton.Text = 'my_sobel';
            app.sobelButton = uibutton(app.UIFigure, 'push');
            app.sobelButton.ButtonPushedFcn = createCallbackFcn(app, @sobelButtonPushed, true);
            app.sobelButton.Text = 'sobel';
            app.my_robertButton = uibutton(app.UIFigure, 'push');
            app.my_robertButton.ButtonPushedFcn = createCallbackFcn(app, @my_robertButtonPushed, true);
            app.my_robertButton.Text = 'my_robert';
            app.robertButton = uibutton(app.UIFigure, 'push');
            app.robertButton.ButtonPushedFcn = createCallbackFcn(app, @robertButtonPushed, true);
            app.robertButton.Text = 'robert';
            app.my_perwittButton = uibutton(app.UIFigure, 'push');
            app.my_perwittButton.ButtonPushedFcn = createCallbackFcn(app, @my_perwittButtonPushed, true);
            app.my_perwittButton.Text = 'my_perwitt';
            app.perwittButton = uibutton(app.UIFigure, 'push');
            app.perwittButton.ButtonPushedFcn = createCallbackFcn(app, @perwittButtonPushed, true);
            app.perwittButton.Text = 'perwitt';
            app.UIAxes = uiaxes(app.UIFigure);
            app.toGrayButton = uibutton(app.UIFigure, 'push');
            app.toGrayButton.ButtonPushedFcn = createCallbackFcn(app, @toGrayButtonPushed, true);
            app.toGrayButton.Text = 'toGray';
            app.UIAxes2 = uiaxes(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = my_and_mat_onegradient

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end