classdef wiener_dct < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        UIAxes            matlab.ui.control.UIAxes
        loadButton        matlab.ui.control.Button
        per_resultButton  matlab.ui.control.Button
        pick_YButton      matlab.ui.control.Button
        noise_itButton    matlab.ui.control.Button
    end


    properties (Access = public)
        im1 % Description
        im2
        im3
        im4
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             set(app.UIAxes,'visible','off');
        end

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            app.im1 = imread('apartments.jpg');
            imshow(app.im1 ,'Parent' ,app.UIAxes);
        end

        % Button pushed function: noise_itButton
        function noise_itButtonPushed(app, event)
           app.im1 = imnoise(app.im1,'gaussian',0,0.01);
           imshow(app.im1 ,'Parent' ,app.UIAxes);
        end

        % Button pushed function: pick_YButton
        function pick_YButtonPushed(app, event)
            app.im1 = rgb2ycbcr(app.im1);
            app.im2 = app.im1;
            app.im1 = app.im1(:,:,1);
            app.im3 = app.im1(1,:,:);
            app.im4 = app.im1(:,1,:);
            imshow(app.im1 ,'Parent' ,app.UIAxes);
        end

        % Button pushed function: per_resultButton
        function per_resultButtonPushed(app, event)
            app.im1 = dct2(app.im1);
            coe = app.im1(800:end,500:end).*app.im1(800:end,500:end);
            NoiseVariance = mean(mean(coe));
            beta = 4.0;
            NoiseVariance = beta*NoiseVariance;
            SignalVariance = app.im1.*app.im1 + 0.01;
            WienerFilter = 1 + (NoiseVariance./SignalVariance);
            WienerFilter = 1./WienerFilter;
            IAfterFilter = app.im1.*WienerFilter;
            app.im1 = idct2(IAfterFilter);           
            app.im2(:,:,1) = app.im1;
            app.im2 = ycbcr2rgb(app.im2);
            imshow(app.im2 ,'Parent' ,app.UIAxes);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.per_resultButton = uibutton(app.UIFigure, 'push');
            app.per_resultButton.ButtonPushedFcn = createCallbackFcn(app, @per_resultButtonPushed, true);
            app.per_resultButton.Text = 'per_result';
            app.pick_YButton = uibutton(app.UIFigure, 'push');
            app.pick_YButton.ButtonPushedFcn = createCallbackFcn(app, @pick_YButtonPushed, true);
            app.pick_YButton.Text = 'pick_Y';
            app.noise_itButton = uibutton(app.UIFigure, 'push');
            app.noise_itButton.ButtonPushedFcn = createCallbackFcn(app, @noise_itButtonPushed, true);
            app.noise_itButton.Text = 'noise_it';
        end
    end

    methods (Access = public)

        % Construct app
        function app = wiener_dct

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end